classdef TLS_PCAP_Converter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PCAPtoPointCloudConverterUIFigure  matlab.ui.Figure
        LidarVersionDropDown          matlab.ui.control.DropDown
        LidarVersionDropDownLabel     matlab.ui.control.Label
        TerrestrialLaserScannerLabel  matlab.ui.control.Label
        ProgressField                 matlab.ui.control.EditField
        RunButton                     matlab.ui.control.Button
        GridstepEditField             matlab.ui.control.NumericEditField
        GridstepEditFieldLabel        matlab.ui.control.Label
        BandstoKeepDropDown           matlab.ui.control.DropDown
        BandstoKeepDropDownLabel      matlab.ui.control.Label
        PointstoKeepDropDown          matlab.ui.control.DropDown
        PointstoKeepDropDownLabel     matlab.ui.control.Label
        PcapFileEditField             matlab.ui.control.EditField
        DelayEditField                matlab.ui.control.NumericEditField
        DelayEditFieldLabel           matlab.ui.control.Label
        AngleEditField                matlab.ui.control.NumericEditField
        AngleEditFieldLabel           matlab.ui.control.Label
        DurationEditField_2           matlab.ui.control.NumericEditField
        DurationEditField_2Label      matlab.ui.control.Label
        SaveSettingsButton            matlab.ui.control.Button
        LoadPCAPfileButton            matlab.ui.control.Button
        offsetrEditField              matlab.ui.control.NumericEditField
        offsetrEditFieldLabel         matlab.ui.control.Label
        alpha2EditField               matlab.ui.control.NumericEditField
        alpha2EditFieldLabel          matlab.ui.control.Label
        alpha1EditField               matlab.ui.control.NumericEditField
        alpha1EditFieldLabel          matlab.ui.control.Label
        ScanSettingsLabel             matlab.ui.control.Label
        VelodynePUCKPCAPtoPointCloudLabel  matlab.ui.control.Label
        CalibrationSettingsLabel      matlab.ui.control.Label
        ExportParametersLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        input % Description
        input_file_name % Description
        result % Description
        times % Description
        angle % Description
        first % Description
        pos % Description
        pos2 % Description
        gridStep % Description
        alpha_1 % Description
        alpha_2 % Description
        R % Description
        puck % Description
    end
    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %#exclude settings.mat
            data = load("C:\TLS_Pie_V2\application\settings.mat");
            app.AngleEditField.Value = data.angle;
            app.DurationEditField_2.Value = data.times;
            app.DelayEditField.Value = data.first;
            app.alpha1EditField.Value = data.alpha_1;
            app.alpha2EditField.Value = data.alpha_2;
            app.GridstepEditField.Value = data.gridStep;
            app.offsetrEditField.Value = data.R;
        end

        % Button pushed function: LoadPCAPfileButton
        function LoadPCAPfileButtonPushed(app, event)
            [file,path] = uigetfile('*.pcap');
            app.input_file_name = [fullfile(path,file)];
            app.PcapFileEditField.Value = [fullfile(path,file)];
            app.ProgressField.Value = "Enter Settings and Press Save";
            drawnow;
            figure(app.PCAPtoPointCloudConverterUIFigure)
            switch app.PointstoKeepDropDown.Value
             case 'All'
                 pos = 0;
             case 'X Positive'
                 pos = 1;
             case 'Y Negative'
                 pos = 2;
             end
         switch app.BandstoKeepDropDown.Value
             case 'All'
                 pos2 = 1;
             case 'First,Last'
                 pos2 = 0;
             case 'Middle'
                 pos2 = 2;
         end
         switch app.LidarVersionDropDown.Value
             case 'VLP16'
                 puck = 1;
             case 'VLP32C'
                 puck = 2;
         end
         times = app.DurationEditField_2.Value;
         angle = app.AngleEditField.Value;
         first = app.DelayEditField.Value;
         alpha_1 = app.alpha1EditField.Value;
         alpha_2 = app.alpha2EditField.Value;
         gridStep = app.GridstepEditField.Value;
         R = app.offsetrEditField.Value;
         input_file_name = [app.input_file_name];
         %puck = app.LidarVersionDropDown.Value;
          save('C:\TLS_Pie\application\settings.mat','times','angle','first','gridStep','alpha_1','alpha_2','R','pos','pos2','input_file_name','puck')
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
         app.ProgressField.Value = "Settings Saved Press Run";
            drawnow;
            switch app.PointstoKeepDropDown.Value
             case 'All'
                 pos = 0;
             case 'X Positive'
                 pos = 1;
             case 'Y Negative'
                 pos = 2;
             end
         switch app.BandstoKeepDropDown.Value
             case 'All'
                 pos2 = 1;
             case 'First,Last'
                 pos2 = 0;
             case 'Middle'
                 pos2 = 2;
         end
         switch app.LidarVersionDropDown.Value
             case 'VLP16'
                 puck = 1;
             case 'VLP32C'
                 puck = 2;
         end
         times = app.DurationEditField_2.Value;
         angle = app.AngleEditField.Value;
         first = app.DelayEditField.Value;
         alpha_1 = app.alpha1EditField.Value;
         alpha_2 = app.alpha2EditField.Value;
         gridStep = app.GridstepEditField.Value;
         R = app.offsetrEditField.Value;
         input_file_name = [app.input_file_name];
         %puck = app.LidarVersionDropDown.Value;
          save('C:\TLS_Pie\application\settings.mat','times','angle','first','gridStep','alpha_1','alpha_2','R','pos','pos2','input_file_name','puck')
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.ProgressField.Value = "Processing PCAP File, Please Wait";
            drawnow;
            switch app.PointstoKeepDropDown.Value
             case 'All'
                 pos = 0;
             case 'X Positive'
                 pos = 1;
             case 'Y Negative'
                 pos = 2;
             end
         switch app.BandstoKeepDropDown.Value
             case 'All'
                 pos2 = 1;
             case 'First,Last'
                 pos2 = 0;
             case 'Middle'
                 pos2 = 2;
         end
         switch app.LidarVersionDropDown.Value
             case 'VLP16'
                 puck = 1;
             case 'VLP32C'
                 puck = 2;
         end
         times = app.DurationEditField_2.Value;
         angle = app.AngleEditField.Value;
         first = app.DelayEditField.Value;
         alpha_1 = app.alpha1EditField.Value;
         alpha_2 = app.alpha2EditField.Value;
         gridStep = app.GridstepEditField.Value;
         R = app.offsetrEditField.Value;
         input_file_name = [app.input_file_name];
         %puck = app.LidarVersionDropDown.Value;
          save('C:\TLS_Pie\application\settings.mat','times','angle','first','gridStep','alpha_1','alpha_2','R','pos','pos2','input_file_name', 'puck')
            run 'TLS_PCAP_script';
            app.ProgressField.Value = "Processing Done";
            drawnow;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PCAPtoPointCloudConverterUIFigure = uifigure('Visible', 'off');
            app.PCAPtoPointCloudConverterUIFigure.Name = 'PCAP to Point Cloud Converter';
            app.ExportParametersLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.ExportParametersLabel.Text = 'Export Parameters';
            app.CalibrationSettingsLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.CalibrationSettingsLabel.Text = 'Calibration Settings';
            app.VelodynePUCKPCAPtoPointCloudLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.VelodynePUCKPCAPtoPointCloudLabel.Text = 'Velodyne PUCK PCAP to Point Cloud';
            app.ScanSettingsLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.ScanSettingsLabel.Text = 'Scan Settings';
            app.alpha1EditFieldLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.alpha1EditFieldLabel.Text = 'alpha 1';
            app.alpha1EditField = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'numeric');
            app.alpha1EditField.Tooltip = {'collimation axis'};
            app.alpha2EditFieldLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.alpha2EditFieldLabel.Text = 'alpha 2';
            app.alpha2EditField = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'numeric');
            app.alpha2EditField.Tooltip = {'tilting axis'};
            app.offsetrEditFieldLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.offsetrEditFieldLabel.Text = 'offset r';
            app.offsetrEditField = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'numeric');
            app.offsetrEditField.Tooltip = {'distance between the arm and the optical center of the LiDAR in [m]'};
            app.LoadPCAPfileButton = uibutton(app.PCAPtoPointCloudConverterUIFigure, 'push');
            app.LoadPCAPfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPCAPfileButtonPushed, true);
            app.LoadPCAPfileButton.Text = 'Load PCAP file';
            app.SaveSettingsButton = uibutton(app.PCAPtoPointCloudConverterUIFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.DurationEditField_2Label = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.DurationEditField_2Label.Text = 'Duration';
            app.DurationEditField_2 = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'numeric');
            app.DurationEditField_2.Tooltip = {'Scan duration in tenths of a second'};
            app.DurationEditField_2.Value = 3630;
            app.AngleEditFieldLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'numeric');
            app.AngleEditField.Tooltip = {'LiDAR rotation angle'};
            app.DelayEditFieldLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'numeric');
            app.DelayEditField.Tooltip = {'Time in tenths of a second till start of first frame'};
            app.PcapFileEditField = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'text');
            app.PointstoKeepDropDownLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.PointstoKeepDropDownLabel.Text = 'Points to Keep';
            app.PointstoKeepDropDown = uidropdown(app.PCAPtoPointCloudConverterUIFigure);
            app.PointstoKeepDropDown.Items = {'All', 'X Positive', 'Y Negative'};
            app.PointstoKeepDropDown.Tooltip = {'Default Y Negative. Use ALL for displaying A2 error'};
            app.PointstoKeepDropDown.Value = 'Y Negative';
            app.BandstoKeepDropDownLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.BandstoKeepDropDownLabel.Text = 'Bands to Keep';
            app.BandstoKeepDropDown = uidropdown(app.PCAPtoPointCloudConverterUIFigure);
            app.BandstoKeepDropDown.Items = {'All', 'First,Last', 'Middle'};
            app.BandstoKeepDropDown.Tooltip = {'Scan is divided in section. Pick parts of scan to keep'};
            app.BandstoKeepDropDown.Value = 'All';
            app.GridstepEditFieldLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.GridstepEditFieldLabel.Text = 'Grid step';
            app.GridstepEditField = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'numeric');
            app.GridstepEditField.Tooltip = {'Subsamplingn in [m] / Set to 0 to keep all the values'};
            app.RunButton = uibutton(app.PCAPtoPointCloudConverterUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ProgressField = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'text');
            app.ProgressField.Value = 'Load PCAP File';
            app.TerrestrialLaserScannerLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.TerrestrialLaserScannerLabel.Text = 'Terrestrial Laser Scanner';
            app.LidarVersionDropDownLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.LidarVersionDropDownLabel.Text = 'Lidar Version';
            app.LidarVersionDropDown = uidropdown(app.PCAPtoPointCloudConverterUIFigure);
            app.LidarVersionDropDown.Items = {'VLP16', 'VLP32C'};
            app.LidarVersionDropDown.Tooltip = {'Select your Puck Version'};
            app.LidarVersionDropDown.Value = 'VLP16';
            % Show the figure after all components are created
            app.PCAPtoPointCloudConverterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TLS_PCAP_Converter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PCAPtoPointCloudConverterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PCAPtoPointCloudConverterUIFigure)
        end
    end
end