classdef TLS_Velodyne_Converter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PCAPtoPointCloudConverterUIFigure  matlab.ui.Figure
        LidarVersionDropDown           matlab.ui.control.DropDown
        LidarVersionDropDownLabel      matlab.ui.control.Label
        TerrestrialLaserScannerLabel   matlab.ui.control.Label
        ProgressField                  matlab.ui.control.EditField
        RunButton                      matlab.ui.control.Button
        GridstepEditField              matlab.ui.control.NumericEditField
        GridstepEditFieldLabel         matlab.ui.control.Label
        BandstoKeepDropDown            matlab.ui.control.DropDown
        BandstoKeepDropDownLabel       matlab.ui.control.Label
        PointstoKeepDropDown           matlab.ui.control.DropDown
        PointstoKeepDropDownLabel      matlab.ui.control.Label
        PcapFileEditField              matlab.ui.control.EditField
        DelayEditField                 matlab.ui.control.NumericEditField
        DelayEditFieldLabel            matlab.ui.control.Label
        AngleEditField                 matlab.ui.control.NumericEditField
        AngleEditFieldLabel            matlab.ui.control.Label
        DurationEditField_2            matlab.ui.control.NumericEditField
        DurationEditField_2Label       matlab.ui.control.Label
        SaveSettingsButton             matlab.ui.control.Button
        LoadPCAPfileButton             matlab.ui.control.Button
        offsetrEditField               matlab.ui.control.NumericEditField
        offsetrEditFieldLabel          matlab.ui.control.Label
        alpha2EditField                matlab.ui.control.NumericEditField
        alpha2EditFieldLabel           matlab.ui.control.Label
        alpha1EditField                matlab.ui.control.NumericEditField
        alpha1EditFieldLabel           matlab.ui.control.Label
        ScanSettingsLabel              matlab.ui.control.Label
        VelodynePCAPtoPointCloudLabel  matlab.ui.control.Label
        CalibrationSettingsLabel       matlab.ui.control.Label
        ExportParametersLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        input % Description
        input_file_name % Description
        result % Description
        times % Description
        angle % Description
        first % Description
        pos % Description
        pos2 % Description
        gridStep % Description
        alpha_1 % Description
        alpha_2 % Description
        R % Description
        puck % Description
    end
    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            my_settings = ("C:\TLS_Velodyne\application\settings.mat");
            disp(['Settings file path: ', my_settings]);

            % Check if the file exists before loading
            if isfile(my_settings)
                disp('The settings.mat file exists. Loading...');
                % Load the settings
                data = load(my_settings);
                disp('The settings.mat file has been loaded successfully.');
            else
                disp('The settings.mat file does NOT exist.');
            end

            app.AngleEditField.Value = data.angle;
            app.DurationEditField_2.Value = data.times;
            app.DelayEditField.Value = data.first;
            app.alpha1EditField.Value = data.alpha_1;
            app.alpha2EditField.Value = data.alpha_2;
            app.GridstepEditField.Value = data.gridStep;
            app.offsetrEditField.Value = data.R;
        end

        % Button pushed function: LoadPCAPfileButton
        function LoadPCAPfileButtonPushed(app, event)
            [file,path] = uigetfile('*.pcap');
            app.input_file_name = [fullfile(path,file)];
            app.PcapFileEditField.Value = [fullfile(path,file)];
            app.ProgressField.Value = "Enter Settings and Press Save";
            drawnow;
            figure(app.PCAPtoPointCloudConverterUIFigure)
            switch app.PointstoKeepDropDown.Value
             case 'All'
                 pos = 0;
             case 'X Positive'
                 pos = 1;
             case 'X Negative'
                 pos = 2;
             end
         switch app.BandstoKeepDropDown.Value
             case 'All'
                 pos2 = 1;
             case 'First,Last'
                 pos2 = 0;
             case 'Middle'
                 pos2 = 2;
         end
         switch app.LidarVersionDropDown.Value
             case 'VLP16'
                 puck = 1;
             case 'VLP32C'
                 puck = 2;
         end

         times = app.DurationEditField_2.Value;
         angle = app.AngleEditField.Value;
         first = app.DelayEditField.Value;
         alpha_1 = app.alpha1EditField.Value;
         alpha_2 = app.alpha2EditField.Value;
         gridStep = app.GridstepEditField.Value;
         R = app.offsetrEditField.Value;
         input_file_name = [app.input_file_name];
         
         % Save the settings
         settings_file = ("C:\TLS_Velodyne\application\settings.mat");
         save(settings_file, 'times', 'angle', 'first', 'gridStep', 'alpha_1', 'alpha_2', 'R', 'pos', 'pos2', 'input_file_name', 'puck')

         % Check if the file was saved correctly
         if isfile(settings_file)
             disp('The settings.mat file was saved correctly.');
         else
             disp('The settings.mat file was NOT saved.');
         end
          
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
         app.ProgressField.Value = "Settings Saved Press Run";
            drawnow;
            switch app.PointstoKeepDropDown.Value
             case 'All'
                 pos = 0;
             case 'X Positive'
                 pos = 1;
             case 'X Negative'
                 pos = 2;
             end
         switch app.BandstoKeepDropDown.Value
             case 'All'
                 pos2 = 1;
             case 'First,Last'
                 pos2 = 0;
             case 'Middle'
                 pos2 = 2;
         end
         switch app.LidarVersionDropDown.Value
             case 'VLP16'
                 puck = 1;
             case 'VLP32C'
                 puck = 2;
         end
         times = app.DurationEditField_2.Value;
         angle = app.AngleEditField.Value;
         first = app.DelayEditField.Value;
         alpha_1 = app.alpha1EditField.Value;
         alpha_2 = app.alpha2EditField.Value;
         gridStep = app.GridstepEditField.Value;
         R = app.offsetrEditField.Value;
         input_file_name = [app.input_file_name];
         
          % Save the settings
         settings_file = ("C:\TLS_Velodyne\application\settings.mat");
         save(settings_file, 'times', 'angle', 'first', 'gridStep', 'alpha_1', 'alpha_2', 'R', 'pos', 'pos2', 'input_file_name', 'puck')

         % Check if the file was saved correctly
         if isfile(settings_file)
             disp('The settings.mat file was saved correctly.');
         else
             disp('The settings.mat file was NOT saved.');
         end


        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.ProgressField.Value = "Processing PCAP File, Please Wait";
            drawnow;
            switch app.PointstoKeepDropDown.Value
             case 'All'
                 pos = 0;
             case 'X Positive'
                 pos = 1;
             case 'X Negative'
                 pos = 2;
             end
         switch app.BandstoKeepDropDown.Value
             case 'All'
                 pos2 = 1;
             case 'First,Last'
                 pos2 = 0;
             case 'Middle'
                 pos2 = 2;
         end
         switch app.LidarVersionDropDown.Value
             case 'VLP16'
                 puck = 1;
             case 'VLP32C'
                 puck = 2;
         end

         originalTimes = app.DurationEditField_2.Value;  % Save the original times value for comparison later
         times = originalTimes;
         angle = app.AngleEditField.Value;

         % angle validation
         if isempty(angle) || ~isnumeric(angle) || angle < 45 || angle > 360
             angle = 360;
             app.AngleEditField.Value = 360;  % Update the UI component value
             uialert(app.PCAPtoPointCloudConverterUIFigure, 'Angle is outside the valid range (45-360). Setting angle to 360. Correct Angle value for next run', 'Angle Error');
         end

         first = app.DelayEditField.Value;
         alpha_1 = app.alpha1EditField.Value;
         alpha_2 = app.alpha2EditField.Value;
         gridStep = app.GridstepEditField.Value;
         R = app.offsetrEditField.Value;
         input_file_name = [app.input_file_name];

         % Set the script path
         my_script = ("C:\TLS_Velodyne\application\TLS_Velodyne_script.m");

         % Save the settings
         settings_file = ("C:\TLS_Velodyne\application\settings.mat");
         save(settings_file, 'times', 'angle', 'first', 'gridStep', 'alpha_1', 'alpha_2', 'R', 'pos', 'pos2', 'input_file_name', 'puck')

         % Check if the file was saved correctly
         if isfile(settings_file)
             disp('The settings.mat file was saved correctly.');
         else
             disp('The settings.mat file was NOT saved.');
         end

         % Load the script content into a string
         script_content = fileread(my_script);

         % Evaluate the script content in the base workspace
         try
             evalin('base', script_content);
         catch ME
             uialert(app.PCAPtoPointCloudConverterUIFigure, ['Error when running script: ', ME.message, '. File path: ', my_script], 'Error');
         end

         % Load the settings file including 'times' after it may have been updated by the script
         loadedVariables = load(settings_file, 'times');
         % Extract the 'times' variable
         times = loadedVariables.times;

         % Check if 'times' exceeds the number of frames minus the delay
         if times ~= originalTimes
             uialert(app.PCAPtoPointCloudConverterUIFigure, 'Entered time value is too large. It has been replaced with the maximum allowed Time. Correct the Scan Time for your next run', 'Time Error');
             % Update the UI field value
             app.DurationEditField_2.Value = times;
             
         end

            app.ProgressField.Value = "Processing Done";
            drawnow;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PCAPtoPointCloudConverterUIFigure = uifigure('Visible', 'off');
            app.PCAPtoPointCloudConverterUIFigure.Name = 'PCAP to Point Cloud Converter';
            app.ExportParametersLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.ExportParametersLabel.Text = 'Export Parameters';
            app.CalibrationSettingsLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.CalibrationSettingsLabel.Text = 'Calibration Settings';
            app.VelodynePCAPtoPointCloudLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.VelodynePCAPtoPointCloudLabel.Text = 'Velodyne PCAP to Point Cloud';
            app.ScanSettingsLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.ScanSettingsLabel.Text = 'Scan Settings';
            app.alpha1EditFieldLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.alpha1EditFieldLabel.Text = 'alpha 1';
            app.alpha1EditField = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'numeric');
            app.alpha1EditField.Tooltip = {'collimation axis'};
            app.alpha2EditFieldLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.alpha2EditFieldLabel.Text = 'alpha 2';
            app.alpha2EditField = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'numeric');
            app.alpha2EditField.Tooltip = {'tilting axis'};
            app.offsetrEditFieldLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.offsetrEditFieldLabel.Text = 'offset r';
            app.offsetrEditField = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'numeric');
            app.offsetrEditField.Tooltip = {'distance between the arm and the optical center of the LiDAR in [m]'};
            app.LoadPCAPfileButton = uibutton(app.PCAPtoPointCloudConverterUIFigure, 'push');
            app.LoadPCAPfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPCAPfileButtonPushed, true);
            app.LoadPCAPfileButton.Text = 'Load PCAP file';
            app.SaveSettingsButton = uibutton(app.PCAPtoPointCloudConverterUIFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.DurationEditField_2Label = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.DurationEditField_2Label.Text = 'Duration';
            app.DurationEditField_2 = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'numeric');
            app.DurationEditField_2.Tooltip = {'Scan duration in tenths of a second'};
            app.DurationEditField_2.Value = 3630;
            app.AngleEditFieldLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'numeric');
            app.AngleEditField.Tooltip = {'LiDAR rotation angle'};
            app.DelayEditFieldLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'numeric');
            app.DelayEditField.Tooltip = {'Time in tenths of a second till start of first frame'};
            app.PcapFileEditField = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'text');
            app.PointstoKeepDropDownLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.PointstoKeepDropDownLabel.Text = 'Points to Keep';
            app.PointstoKeepDropDown = uidropdown(app.PCAPtoPointCloudConverterUIFigure);
            app.PointstoKeepDropDown.Items = {'All', 'X Positive', 'X Negative'};
            app.PointstoKeepDropDown.Tooltip = {'Default X Negative. Use ALL for displaying A2 error'};
            app.PointstoKeepDropDown.Value = 'X Negative';
            app.BandstoKeepDropDownLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.BandstoKeepDropDownLabel.Text = 'Bands to Keep';
            app.BandstoKeepDropDown = uidropdown(app.PCAPtoPointCloudConverterUIFigure);
            app.BandstoKeepDropDown.Items = {'All', 'First,Last', 'Middle'};
            app.BandstoKeepDropDown.Tooltip = {'Scan is divided in section. Pick parts of scan to keep'};
            app.BandstoKeepDropDown.Value = 'All';
            app.GridstepEditFieldLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.GridstepEditFieldLabel.Text = 'Grid step';
            app.GridstepEditField = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'numeric');
            app.GridstepEditField.Tooltip = {'Subsamplingn in [m] / Set to 0 to keep all the values'};
            app.RunButton = uibutton(app.PCAPtoPointCloudConverterUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ProgressField = uieditfield(app.PCAPtoPointCloudConverterUIFigure, 'text');
            app.ProgressField.Value = 'Load PCAP File';
            app.TerrestrialLaserScannerLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.TerrestrialLaserScannerLabel.Text = 'Terrestrial Laser Scanner';
            app.LidarVersionDropDownLabel = uilabel(app.PCAPtoPointCloudConverterUIFigure);
            app.LidarVersionDropDownLabel.Text = 'Lidar Version';
            app.LidarVersionDropDown = uidropdown(app.PCAPtoPointCloudConverterUIFigure);
            app.LidarVersionDropDown.Items = {'VLP16', 'VLP32C'};
            app.LidarVersionDropDown.Tooltip = {'Select your Puck Version'};
            app.LidarVersionDropDown.Value = 'VLP16';
            % Show the figure after all components are created
            app.PCAPtoPointCloudConverterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TLS_Velodyne_Converter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PCAPtoPointCloudConverterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PCAPtoPointCloudConverterUIFigure)
        end
    end
end